% description: Copyright 2022 The MathWorks
% Inc.
classdef DigitalClock < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigureClock       matlab.ui.Figure
    GridBackground      matlab.ui.container.GridLayout
    ImageClockFrame     matlab.ui.control.Image
    GridDisplaysTop     matlab.ui.container.GridLayout
    GridDisplaysTime    matlab.ui.container.GridLayout
    GridDisplaysBottom  matlab.ui.container.GridLayout
    GridDisplaysDate    matlab.ui.container.GridLayout
  end

  properties (Constant = true)
    % Internal App timer duration
    TIMER_PERIOD = 0.5;
    % 7-segment displays parameters
    TIME_BACKGROUND_COLOR = [127 127   0]/255; % RGB
    TIME_SEGMENTS_COLOR   = [131 131   0]/255; % RGB
    DATE_BACKGROUND_COLOR = [  0   0   0]/255; % RGB
    DATE_SEGMENTS_COLOR   = [255   0   0]/255; % RGB
    MINUS_SYMBOL          = 0b01000000;
    NB_PAIRS = 3;
    NB_DISPLAYS = 2;
  end

  properties (Access = private)
    % Main timer of the App to check emulated registers content
    MainTimer
    % Objects to Group the three 7-segment displays
    DisplaysTimeGroup
    DisplaysDateGroup
    % Save previous values to update only the new ones
    timeArrayOld
    dateArrayOld
  end

  properties (Access = public)
    % Indicates if the App is running or not
    Running = 'off';
  end
  
  methods (Access = private)
    function [] = TimerMain(app)
      % Get current date and time
      dateTimeCell = cellstr(datetime('now','TimeZone','local','Format','dd:MM:yy HH:mm:ss'));
      % Separate date and time
      dateTimeSplit = split(dateTimeCell);
      % Separate char digits within each pairs
      dateCell = regexp(split(dateTimeSplit{1},':')','\d','match')';
      timeCell = regexp(split(dateTimeSplit{2},':')','\d','match')';
      % Initialization
      dateArray = nan(app.NB_PAIRS,app.NB_DISPLAYS);
      timeArray = nan(app.NB_PAIRS,app.NB_DISPLAYS);
      % Transform each pairs of char into numbers
      for k=1:1:app.NB_PAIRS
        % Create the date and time values to display
        dateArray(k,:) = str2double(dateCell{k});
        timeArray(k,:) = str2double(timeCell{k});
      end
      % Refresh the 7-segment displays time group
      for k=1:1:app.NB_PAIRS
        if (timeArray(k,1) ~= app.timeArrayOld(k,1))
          setSevenSegmentDisplay(app.DisplaysTimeGroup,encoder(app,timeArray(k,1)),2*k-1);
        end
        if (timeArray(k,2) ~= app.timeArrayOld(k,2))
          setSevenSegmentDisplay(app.DisplaysTimeGroup,encoder(app,timeArray(k,2)),2*k);
        end
      end
      % Refresh the 7-segment displays date group only once when the app starts
      if any(isnan(app.dateArrayOld))
        offset = 0;
        for k=1:1:app.NB_PAIRS
          if (dateArray(k,1) ~= app.dateArrayOld(k,1))
            setSevenSegmentDisplay(app.DisplaysDateGroup,encoder(app,dateArray(k,1)),2*k-1+offset);
          end
          if (dateArray(k,2) ~= app.dateArrayOld(k,2))
            setSevenSegmentDisplay(app.DisplaysDateGroup,encoder(app,dateArray(k,2)),2*k+offset);
          end
          offset = offset+1;
        end
      end
      app.timeArrayOld = timeArray;
      app.dateArrayOld = dateArray;
    end    

    function binaryValue = encoder(~,decimalValue)
      % Map decimal number onto 7-segment digit encoding
      switch (decimalValue)
        case 0
          binaryValue = 0b00111111; % uint8(0)
        case 1
          binaryValue = 0b00000110; % uint8(1)
        case 2
          binaryValue = 0b01011011; % uint8(2)
        case 3
          binaryValue = 0b01001111; % uint8(3)
        case 4
          binaryValue = 0b01100110; % uint8(4)
        case 5
          binaryValue = 0b01101101; % uint8(5)
        case 6
          binaryValue = 0b01111101; % uint8(6)
        case 7
          binaryValue = 0b00000111; % uint8(7)
        case 8
          binaryValue = 0b01111111; % uint8(8)
        case 9
          binaryValue = 0b01101111; % uint8(9)
        case 10
          binaryValue = 0b01000000; % uint8(-)
        otherwise
          % Incorrect provided value => do not update the segments
          binaryValue = [];
      end
    end
  end

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function StartupFcn(app)
      % Command to instanciate the object in the workspace
      % >> app = DigitalClock;

      % Instanciate two 7-Segment displays group component
      app.DisplaysTimeGroup = SevenSegmentDisplays(app.NB_PAIRS*app.NB_DISPLAYS,app.GridDisplaysTime,...
                                                   app.TIME_SEGMENTS_COLOR,app.TIME_BACKGROUND_COLOR);
      app.DisplaysDateGroup = SevenSegmentDisplays(app.NB_PAIRS*app.NB_DISPLAYS+2,app.GridDisplaysDate,...
                                                   app.DATE_SEGMENTS_COLOR,app.DATE_BACKGROUND_COLOR);
      % Change segments color to black for the normal App behavior
      app.DisplaysTimeGroup.SegmentsColor = [0 0 0];
      % Add minus symbols between Day-Month-Year
      setSevenSegmentDisplay(app.DisplaysDateGroup,app.MINUS_SYMBOL,3);
      setSevenSegmentDisplay(app.DisplaysDateGroup,app.MINUS_SYMBOL,6);
      % Initialize old time values
      app.timeArrayOld = nan(app.NB_PAIRS,app.NB_DISPLAYS);
      app.dateArrayOld = nan(app.NB_PAIRS,app.NB_DISPLAYS);
      % Setup of the timer to poll status or change values of the emulated HW registers
      app.MainTimer = timer('Period',app.TIMER_PERIOD,'ExecutionMode','fixedRate');
      % Function that is periodically called from the timer
      app.MainTimer.TimerFcn = @(~,~)TimerMain(app);
      start(app.MainTimer);
      % Instanciation of the App done
      app.Running = 'on';
    end

    % Close request function: UIFigureClock
    function UIFigureClockCloseRequest(app, event)
      delete(app);
      stop(timerfind);
      delete(timerfind);
      clc;
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigureClock = uifigure('Visible', 'off');
      app.UIFigureClock.Name = 'Digital Clock @ MathWorks';
      app.UIFigureClock.CloseRequestFcn = createCallbackFcn(app, @UIFigureClockCloseRequest, true);
      app.GridBackground = uigridlayout(app.UIFigureClock);
      app.GridDisplaysBottom = uigridlayout(app.GridBackground);
      app.GridDisplaysDate = uigridlayout(app.GridDisplaysBottom);
      app.GridDisplaysTop = uigridlayout(app.GridBackground);
      app.GridDisplaysTime = uigridlayout(app.GridDisplaysTop);
      app.ImageClockFrame = uiimage(app.GridBackground);
      app.ImageClockFrame.ImageSource = 'ClockFrame.png';
      % Show the figure after all components are created
      app.UIFigureClock.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = DigitalClock

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigureClock)

      % Execute the startup function
      runStartupFcn(app, @StartupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigureClock)
    end
  end
end