% description: Copyright 2022 The MathWorks
% Inc.
classdef SevenSegmentDisplaysApp < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure    matlab.ui.Figure
    GridLayout  matlab.ui.container.GridLayout
  end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% Class SevenSegmentDisplays
% Goal  : This class contains all properties and methods needed to instanciate
%         and use an App UI component implementing a group of 7-segment displays
%         Clicking the "Run" button will just produce an empty UIFigure window.
%         To instanciate and use the class, run a script such as the one in the
%         "AppDesigner" folder called "TestSevenSegmentDisplays.m"
% Copyright 2022 The MathWorks, Inc.
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

  % Variables belonging to the object with direct external access
  properties (Access = public)
    Mask;
    Container;
    SegmentsColor(1,3) {mustBeNonnegative,mustBeNumeric} = [1 1 1];
    BackgroundColor(1,3) {mustBeNonnegative,mustBeNumeric} = [0 0 0];
    Endianness char {mustBeMember(Endianness,{'Left','Right'})} = 'Left';
  end

  % Methods of the class that can be accessed within the object
  methods (Access = public)
    function SevenSegmentDisplaysLayout(app,nbDisplays,segsColor,backColor)
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    % Function SevenSegmentDisplaysLayout()
    % Goal  : Construct a layout of several 7-segment displays instances of the
    %         SevenSegmentDisplays class
    % IN    : - nbDisplays: number of 7-segment displays in the group
    %         - segsColor : color of the display' segments
    %         - backColor : color of the display's background
    %                       RGB color vector with values between 0 and 1
    % IN/OUT: - app       : object of the class
    % OUT   : -
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
      % Load the labeled Mask of the 7-segment display graphical component
      app.Mask = load('..\UIClass\SevenSegmentMask.mat');
      % Keep container properties in the SevenSegmentDisplays object
      app.Container = app.GridLayout;
      % Keep color properties in the SevenSegmentDisplays object
      app.SegmentsColor = segsColor;
      app.BackgroundColor = backColor;
      app.UIFigure.Color = app.BackgroundColor;
      % Set the main UI figure name for the group of 7-segment displays component
      figureTitle = ['Group of ' num2str(nbDisplays) ' ''7-segment displays'''];
      app.UIFigure.Name = figureTitle;
      % Instanciate each UI axes to add them to the UIFigure
      column = 1;
      for k=1:1:nbDisplays
        % Desired number of 7-segment displays are created to fullfil the space
        axHandler = uiaxes(app.Container,'LooseInset',zeros(1,4),...
                           'xtick',[],'ytick',[],'Interactions',[]);
        axHandler.Layout.Row = 1;
        axHandler.Layout.Column = column;
        column = column + 1;
        axis(axHandler,'off');
        axHandler.Toolbar.Visible = 'off';
        axis(axHandler,'tight');
        % Enable all 7 segments plus the decimal point by default
        updateDigit(app,(1:1:8),k);
      end
    end

    function setSevenSegmentDisplay(app,binaryValue,digitID)
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    % Function setSevenSegmentDisplay()
    % Goal  : Set function to change the state of one 7-segment display
    % IN    : - binaryValue  : uint8 value that represents segments to switch on
    %         - digitID      : ID of the 7-segment display to update
    % IN/OUT: - app          : object of the class
    % OUT   : -
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
      % Constants
      SEGMENTS_DIGIT_P = 0b10000000; % Decimal point segment encoding
      % Remove the decimal point bit to extract the digit only
      decimalPoint = bitand(binaryValue,SEGMENTS_DIGIT_P);
      binaryValue  = bitand(binaryValue,bitcmp(SEGMENTS_DIGIT_P));
      % New 7-segment encoded value to write
      switch (binaryValue)
        case 0b00111111 % uint8(0)
          segments = [1 2 3 4 5 6];
        case 0b00000110 % uint8(1)
          segments = [2 3];
        case 0b01011011 % uint8(2)
          segments = [1 2 4 5 7];
        case 0b01001111 % uint8(3)
          segments = [1 2 3 4 7];
        case 0b01100110 % uint8(4)
          segments = [2 3 6 7];
        case 0b01101101 % uint8(5)
          segments = [1 3 4 6 7];
        case 0b01111101 % uint8(6)
          segments = [1 3 4 5 6 7];
        case 0b00000111 % uint8(7)
          segments = [1 2 3];
        case 0b01111111 % uint8(8)
          segments = [1 2 3 4 5 6 7];
        case 0b01101111 % uint8(9)
          segments = [1 2 3 4 6 7];
        case 0b01011110 % a.
          segments = [3 4 5 7 8];
        case 0b01011100 % b.
          segments = [3 4 5 6 7 8];
        case 0b01111100 % c.
          segments = [4 5 7 8];
        case 0b01011000 % d.
          segments = [2 3 4 5 7 8];
        case 0b01000000 % uint8(-)
          segments = 7;
        otherwise
          % Incorrect 7 segments encoded value => do not update the segments
          segments = [];
      end
      % Add the decimal point to the digit if present
      if (decimalPoint ~= 0)
        segments = [segments 8];
      end
      if ~isempty(segments)
        updateDigit(app,segments,digitID);
      end
    end
  end
  
  methods (Access = private)
    function updateDigit(app,segments,digitID)
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    % Function updateDigit()
    % Goal  : Set function to change the state of one 7-segment display
    % IN    : - app        : object of the class
    %         - segments   : list of segments indices to switch on
    %         - digitID    : ID of the 7-segment display to update
    % IN/OUT: -
    % OUT   : -
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
      % Initialization
      nbDigits = length(app.Container.Children);
      [nbRows,nbCols] = size(app.Mask.labels);
      digit = permute(repmat(app.BackgroundColor',[1 nbRows nbCols]),[2 3 1]);
      % Enable needed pixels
      for m=1:1:nbRows
        for n=1:1:nbCols
          if any(app.Mask.labels(m,n) == segments)
            digit(m,n,:) = app.SegmentsColor;
          end
        end
      end
      % Order of the displays, start from the left or the right
      if strcmp(app.Endianness,'Right')
        image(digit,'parent',app.Container.Children(nbDigits-digitID+1));
      else
        image(digit,'parent',app.Container.Children(digitID));
      end
    end
  end  

  % Callbacks that handle component events
  methods (Access = private)

    % Close request function: UIFigure
    function UIFigureCloseRequest(app, event)
      delete(app);
      clc;
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'UI Figure';
      app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
      app.GridLayout = uigridlayout(app.UIFigure);
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = SevenSegmentDisplaysApp

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end